classdef edge_detection_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        SelectFilterDropDown       matlab.ui.control.DropDown
        SelectFilterDropDownLabel  matlab.ui.control.Label
        SelectanImageButton        matlab.ui.control.Button
        UIAxes2                    matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        img
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectanImageButton
        function SelectanImageButtonPushed(app, event)
            [fn,fp] = uigetfile({'*.jpeg;*.png;'});
            app.img = imread([fp,fn]);    
            imshow(app.img,'Parent',app.UIAxes);
            app.img = im2gray(app.img);
        end

        % Value changed function: SelectFilterDropDown
        function SelectFilterDropDownValueChanged(app, event)
            value = app.SelectFilterDropDown.Value;
            sobel = edge(app.img,"sobel");
            prewitt = edge(app.img,"prewitt");
            roberts = edge(app.img,"roberts");
            canny = edge(app.img,"canny");  
            switch value
                case 'sobel'
                    imshow(sobel,'Parent',app.UIAxes2)
                case 'prewitt'
                    imshow(prewitt,'Parent',app.UIAxes2)
                case 'roberts'
                    imshow(roberts,'Parent',app.UIAxes2)
                case 'canny'
                    imshow(canny,'Parent',app.UIAxes2)
                case 'choose'
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Edge')
            app.SelectanImageButton = uibutton(app.UIFigure, 'push');
            app.SelectanImageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectanImageButtonPushed, true);
            app.SelectanImageButton.Text = 'Select an Image';
            app.SelectFilterDropDownLabel = uilabel(app.UIFigure);
            app.SelectFilterDropDownLabel.Text = 'Select Filter';
            app.SelectFilterDropDown = uidropdown(app.UIFigure);
            app.SelectFilterDropDown.Items = {'choose', 'sobel', 'prewitt', 'roberts', 'canny'};
            app.SelectFilterDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectFilterDropDownValueChanged, true);
            app.SelectFilterDropDown.Value = 'choose';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = edge_detection_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end