classdef histogram_equilization < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        HISTOGRAMEQUILIZATIONLabel  matlab.ui.control.Label
        ProcessImageButton          matlab.ui.control.Button
        SelectIMAGEButton           matlab.ui.control.Button
        UIAxes_2                    matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectIMAGEButton
        function SelectIMAGEButtonPushed(app, event)
            % Open file dialog
            [filename, filepath] = uigetfile({'*.jpg;*.png;*.bmp;*.tif;*.*'},'Select an image');                                            
            if isequal(filename,0)
                return; % if user cancels
            end        
            % Read image
            img = imread(fullfile(filepath, filename));        
            % Show image in your app's axes
            imshow(img, 'Parent', app.UIAxes);
            axis(app.UIAxes,"off");
        end

        % Button pushed function: ProcessImageButton
        function ProcessImageButtonPushed(app, event)
            % Get the image currently displayed in Axes1
            img = getimage(app.UIAxes);
        
            if isempty(img)
                uialert(app.UIFigure, 'Please load an image first.', 'Error');
                return;
            end
        
            % Apply histogram equalization
            if size(img,3) == 1
                imgEq = histeq(img);   % grayscale
            else
                imgEq = img;           % RGB
                for c = 1:3
                    imgEq(:,:,c) = histeq(img(:,:,c));
                end
            end
        
            % Show processed image in Axes2
            imshow(imgEq, 'Parent', app.UIAxes_2);
            axis(app.UIAxes_2,"off");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Processed')
            zlabel(app.UIAxes_2, 'Z')
            app.SelectIMAGEButton = uibutton(app.UIFigure, 'push');
            app.SelectIMAGEButton.ButtonPushedFcn = createCallbackFcn(app, @SelectIMAGEButtonPushed, true);
            app.SelectIMAGEButton.Text = 'Select IMAGE';
            app.ProcessImageButton = uibutton(app.UIFigure, 'push');
            app.ProcessImageButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessImageButtonPushed, true);
            app.ProcessImageButton.Text = 'Process Image';
            app.HISTOGRAMEQUILIZATIONLabel = uilabel(app.UIFigure);
            app.HISTOGRAMEQUILIZATIONLabel.Text = 'HISTOGRAM EQUILIZATION';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = histogram_equilization

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end