classdef image_sharpening < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        CustomSharpenButton   matlab.ui.control.Button
        DefaultSharpenButton  matlab.ui.control.Button
        SelectImageButton     matlab.ui.control.Button
        IMAGESHARPENINGLabel  matlab.ui.control.Label
        UIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % Images
        OriginalImage       % The image loaded by user
        SharpDefault        % Default sharpened image
        SharpCustom         % Custom sharpened image

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectImageButton
        function SelectImageButtonPushed(app, event)
            [file, path] = uigetfile({'*.png;*.jpg;*.tif;*.bmp'}, 'Select an image');
            if isequal(file,0), return; end
            app.OriginalImage = imread(fullfile(path,file));
            imshow(app.OriginalImage,'Parent',app.UIAxes);
            title(app.UIAxes,'Original Image');
        end

        % Button pushed function: DefaultSharpenButton
        function DefaultSharpenButtonPushed(app, event)
             if isempty(app.OriginalImage)
                uialert(app.UIFigure,'Please select an image first!','Error');
                return;
            end
            app.SharpDefault = imsharpen(app.OriginalImage);
            imshow(app.SharpDefault,'Parent',app.UIAxes);
            title(app.UIAxes,'Default Sharpened');
        end

        % Button pushed function: CustomSharpenButton
        function CustomSharpenButtonPushed(app, event)
             if isempty(app.OriginalImage)
                uialert(app.UIFigure,'Please select an image first!','Error');
                return;
            end
            app.SharpCustom = imsharpen(app.OriginalImage,'Radius',2,'Amount',1);
            imshow(app.SharpCustom,'Parent',app.UIAxes);
            title(app.UIAxes,'Custom Sharpened');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original')
            zlabel(app.UIAxes, 'Z')
            app.IMAGESHARPENINGLabel = uilabel(app.UIFigure);
            app.IMAGESHARPENINGLabel.Text = 'IMAGE SHARPENING';
            app.SelectImageButton = uibutton(app.UIFigure, 'push');
            app.SelectImageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImageButtonPushed, true);
            app.SelectImageButton.Text = 'Select Image';
            app.DefaultSharpenButton = uibutton(app.UIFigure, 'push');
            app.DefaultSharpenButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultSharpenButtonPushed, true);
            app.DefaultSharpenButton.Text = {'Default '; 'Sharpen'};
            app.CustomSharpenButton = uibutton(app.UIFigure, 'push');
            app.CustomSharpenButton.ButtonPushedFcn = createCallbackFcn(app, @CustomSharpenButtonPushed, true);
            app.CustomSharpenButton.Text = 'Custom Sharpen';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = image_sharpening

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end