classdef image_smoothening_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        IMAGESMOOTHENINGLabel  matlab.ui.control.Label
        WeinerFilterButton     matlab.ui.control.Button
        NoisyGaussianButton    matlab.ui.control.Button
        SelectImageButton_2    matlab.ui.control.Button
        MedianFilterButton     matlab.ui.control.Button
        MeanFilterButton       matlab.ui.control.Button
        NoisyImageButton       matlab.ui.control.Button
        SelectImageButton      matlab.ui.control.Button
        PART2Label             matlab.ui.control.Label
        PART1Label             matlab.ui.control.Label
        UIAxes2                matlab.ui.control.UIAxes
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        OriginalImage        % For Part 1
        GrayscaleImage
        NoisySPImage
        MeanImage
        MedianImage
    
        OriginalImage2       % For Part 2
        GrayscaleImage2
        NoisyGaussianImage
        WienerImage
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectImageButton
        function SelectImageButtonPushed(app, event)
            [file,path] = uigetfile({'*.png;*.jpg;*.tif;*.bmp'}, 'Select Image');
            if isequal(file,0), return; end
            img = imread(fullfile(path,file));
            app.OriginalImage = img;
            if size(img,3) == 3, app.GrayscaleImage = rgb2gray(img); else app.GrayscaleImage = img; end
            imshow(img,'Parent',app.UIAxes);
            title(app.UIAxes,'Original Image');
        end

        % Button pushed function: NoisyImageButton
        function NoisyImageButtonPushed(app, event)
            if isempty(app.GrayscaleImage)
            uialert(app.UIFigure,'Load image first!','Error'); return;
            end
            app.NoisySPImage = imnoise(app.GrayscaleImage,'salt & pepper',0.02);
            imshow(app.NoisySPImage,'Parent',app.UIAxes);
            title(app.UIAxes,'Salt & Pepper Noise');
        end

        % Button pushed function: MeanFilterButton
        function MeanFilterButtonPushed(app, event)
            if isempty(app.NoisySPImage)
            uialert(app.UIFigure,'Add Salt & Pepper Noise first!','Error'); return;
            end
            app.MeanImage = filter2(fspecial('average',3), app.NoisySPImage)/255;
            imshow(app.MeanImage,'Parent',app.UIAxes);
            title(app.UIAxes,'Mean Filtered');
        end

        % Button pushed function: MedianFilterButton
        function MedianFilterButtonPushed(app, event)
            if isempty(app.NoisySPImage)
            uialert(app.UIFigure,'Add Salt & Pepper Noise first!','Error'); return;
            end
            app.MedianImage = medfilt2(app.NoisySPImage);
            imshow(app.MedianImage,'Parent',app.UIAxes);
            title(app.UIAxes,'Median Filtered');
        end

        % Button pushed function: SelectImageButton_2
        function SelectImageButton_2Pushed(app, event)
            [file,path] = uigetfile({'*.png;*.jpg;*.tif;*.bmp'}, 'Select Image');
            if isequal(file,0), return; end
            img = imread(fullfile(path,file));
            if size(img,3) == 3, app.GrayscaleImage2 = rgb2gray(img); else app.GrayscaleImage2 = img; end
            app.OriginalImage2 = img;
            imshow(img,'Parent',app.UIAxes2);
            title(app.UIAxes2,'Original Image');
        end

        % Button pushed function: NoisyGaussianButton
        function NoisyGaussianButtonPushed(app, event)
            if isempty(app.GrayscaleImage2)
            uialert(app.UIFigure,'Load image first!','Error'); return;
            end
            app.NoisyGaussianImage = imnoise(app.GrayscaleImage2,'gaussian',0,0.025);
            imshow(app.NoisyGaussianImage,'Parent',app.UIAxes2);
            title(app.UIAxes2,'Gaussian Noise');
        end

        % Button pushed function: WeinerFilterButton
        function WeinerFilterButtonPushed(app, event)
            if isempty(app.NoisyGaussianImage)
            uialert(app.UIFigure,'Add Gaussian Noise first!','Error'); return;
            end
            app.WienerImage = wiener2(app.NoisyGaussianImage,[5 5]);
            imshow(app.WienerImage,'Parent',app.UIAxes2);
            title(app.UIAxes2,'Wiener Filtered');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Original')
            zlabel(app.UIAxes2, 'Z')
            app.PART1Label = uilabel(app.UIFigure);
            app.PART1Label.Text = 'PART 1';
            app.PART2Label = uilabel(app.UIFigure);
            app.PART2Label.Text = 'PART 2';
            app.SelectImageButton = uibutton(app.UIFigure, 'push');
            app.SelectImageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImageButtonPushed, true);
            app.SelectImageButton.Text = 'Select Image';
            app.NoisyImageButton = uibutton(app.UIFigure, 'push');
            app.NoisyImageButton.ButtonPushedFcn = createCallbackFcn(app, @NoisyImageButtonPushed, true);
            app.NoisyImageButton.Text = 'Noisy Image';
            app.MeanFilterButton = uibutton(app.UIFigure, 'push');
            app.MeanFilterButton.ButtonPushedFcn = createCallbackFcn(app, @MeanFilterButtonPushed, true);
            app.MeanFilterButton.Text = 'Mean Filter';
            app.MedianFilterButton = uibutton(app.UIFigure, 'push');
            app.MedianFilterButton.ButtonPushedFcn = createCallbackFcn(app, @MedianFilterButtonPushed, true);
            app.MedianFilterButton.Text = 'Median Filter';
            app.SelectImageButton_2 = uibutton(app.UIFigure, 'push');
            app.SelectImageButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectImageButton_2Pushed, true);
            app.SelectImageButton_2.Text = 'Select Image';
            app.NoisyGaussianButton = uibutton(app.UIFigure, 'push');
            app.NoisyGaussianButton.ButtonPushedFcn = createCallbackFcn(app, @NoisyGaussianButtonPushed, true);
            app.NoisyGaussianButton.Text = 'Noisy Gaussian';
            app.WeinerFilterButton = uibutton(app.UIFigure, 'push');
            app.WeinerFilterButton.ButtonPushedFcn = createCallbackFcn(app, @WeinerFilterButtonPushed, true);
            app.WeinerFilterButton.Text = 'Weiner Filter';
            app.IMAGESMOOTHENINGLabel = uilabel(app.UIFigure);
            app.IMAGESMOOTHENINGLabel.Text = 'IMAGE SMOOTHENING';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = image_smoothening_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end