classdef k_means_clustering_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        Button         matlab.ui.control.Button
        DropDown       matlab.ui.control.DropDown
        DropDownLabel  matlab.ui.control.Label
        UIAxes2        matlab.ui.control.UIAxes
        UIAxes         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            [f, p] = uigetfile({'*.jpg;*.png;*.jpeg'});
            if isequal(f, 0), return; end
            he = imread([p f]);
            
            L = imsegkmeans(he, 3); app.p1 = labeloverlay(he, L);
            lab = rgb2lab(he); ab = im2single(lab(:,:,2:3));
            pl = imsegkmeans(ab, 3); app.p2 = labeloverlay(he, pl);
            
            app.p3 = he.*uint8(pl==1);
            app.p4 = he.*uint8(pl==2);
            app.p5 = he.*uint8(pl==3);
            
            L_blue = rescale(lab(:,:,1).*double(pl==3));
            nm = imbinarize(L_blue);
            app.p6 = he.*uint8(nm);
            
            se = strel('disk', 2);
            cm = imfill(imclose(imopen(nm, se), se), 'holes');
            app.p7 = he.*uint8(cm);
            
            app.DropDown.Items = {'Original','RGB','LAB','C1','C2','C3','Nuclei','Clean'};
            app.DropDown.Value = 'Original';
            app.original = he;
            app.DropDownValueChanged(event);
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            switch app.DropDown.Value
                case 'Original', imshow(app.original, 'Parent', app.UIAxes);
                case 'RGB', imshow(app.p1, 'Parent', app.UIAxes);
                case 'LAB', imshow(app.p2, 'Parent', app.UIAxes);
                case 'C1', imshow(app.p3, 'Parent', app.UIAxes);
                case 'C2', imshow(app.p4, 'Parent', app.UIAxes);
                case 'C3', imshow(app.p5, 'Parent', app.UIAxes);
                case 'Nuclei', imshow(app.p6, 'Parent', app.UIAxes);
                case 'Clean', imshow(app.p7, 'Parent', app.UIAxes);
            end
                    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original')
            app.UIAxes2 = uiaxes(app.UIFigure);
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = k_means_clustering_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end