classdef plotting_techniques < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SelectanImageButton           matlab.ui.control.Button
        MORPHOLOGICALOPERATIONSLabel  matlab.ui.control.Label
        UIAxes7                       matlab.ui.control.UIAxes
        UIAxes6                       matlab.ui.control.UIAxes
        UIAxes5                       matlab.ui.control.UIAxes
        UIAxes4                       matlab.ui.control.UIAxes
        UIAxes3                       matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        OriginalImage          % Original selected image
        BinaryImage            % Binary version of the image
        SE   
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectanImageButton
        function SelectanImageButtonPushed(app, event)
            % Let user select an image
            [file,path] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp'}, 'Select an Image');
            if isequal(file,0), return; end
        
            % Read and convert to binary
            img = imread(fullfile(path,file));
            if size(img,3) == 3, img = rgb2gray(img); end
            BW = imbinarize(img);
            se = strel('disk',5);
        
            % Show original
            imshow(BW,'Parent',app.UIAxes); title(app.UIAxes,'Original');
        
            % Dilation
            imshow(imdilate(BW,se),'Parent',app.UIAxes2); title(app.UIAxes2,'Dilation');
        
            % Erosion
            imshow(imerode(BW,se),'Parent',app.UIAxes3); title(app.UIAxes3,'Erosion');
        
            % Opening
            imshow(imopen(BW,se),'Parent',app.UIAxes4); title(app.UIAxes4,'Opening');
        
            % Closing
            imshow(imclose(BW,se),'Parent',app.UIAxes5); title(app.UIAxes5,'Closing');
        
            % Boundary Extraction
            imshow(BW - imerode(BW,se),'Parent',app.UIAxes6); title(app.UIAxes6,'Boundary');
        
            % Skeletonization
            imshow(bwmorph(BW,'skel',Inf),'Parent',app.UIAxes7); title(app.UIAxes7,'Skeleton');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes6 = uiaxes(app.UIFigure);
            title(app.UIAxes6, 'Title')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.UIAxes7 = uiaxes(app.UIFigure);
            title(app.UIAxes7, 'Title')
            xlabel(app.UIAxes7, 'X')
            ylabel(app.UIAxes7, 'Y')
            zlabel(app.UIAxes7, 'Z')
            app.MORPHOLOGICALOPERATIONSLabel = uilabel(app.UIFigure);
            app.MORPHOLOGICALOPERATIONSLabel.Text = 'MORPHOLOGICAL OPERATIONS';
            app.SelectanImageButton = uibutton(app.UIFigure, 'push');
            app.SelectanImageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectanImageButtonPushed, true);
            app.SelectanImageButton.Text = 'Select an Image';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = plotting_techniques

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end