classdef thresholding_techniques < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        SegmentButton                matlab.ui.control.Button
        SelectImageButton            matlab.ui.control.Button
        THRESHOLDINGTECHNIQUESLabel  matlab.ui.control.Label
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        OriginalImage   % Loaded grayscale image
    SegmentedImage  % Segmented result
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectImageButton
        function SelectImageButtonPushed(app, event)
            [file, path] = uigetfile({'*.jpg;*.png;*.bmp'}, 'Select an Image');
            if isequal(file,0), return; end
            
            img = imread(fullfile(path,file));
            
            % Convert to grayscale if RGB
            if size(img,3) == 3
                img = rgb2gray(img);
            end
            
            app.OriginalImage = img;
            
            % Display original image in UIAxes
            imshow(app.OriginalImage, 'Parent', app.UIAxes);
            title(app.UIAxes, 'Original Grayscale Image');
        end

        % Button pushed function: SegmentButton
        function SegmentButtonPushed(app, event)
            if isempty(app.OriginalImage)
                uialert(app.UIFigure, 'Please select an image first!', 'Error');
                return;
            end
            
            % Apply multi-threshold segmentation
            nThresholds = 2;
            thresh = multithresh(app.OriginalImage, nThresholds);
            app.SegmentedImage = imquantize(app.OriginalImage, thresh);
            
            % Display segmented image in UIAxes2
            imshow(label2rgb(app.SegmentedImage), 'Parent', app.UIAxes2);
            title(app.UIAxes2, 'Segmented with Multi-Thresh');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Segmented with Mutli-Thresh')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.THRESHOLDINGTECHNIQUESLabel = uilabel(app.UIFigure);
            app.THRESHOLDINGTECHNIQUESLabel.Text = 'THRESHOLDING TECHNIQUES';
            app.SelectImageButton = uibutton(app.UIFigure, 'push');
            app.SelectImageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImageButtonPushed, true);
            app.SelectImageButton.Text = 'Select Image';
            app.SegmentButton = uibutton(app.UIFigure, 'push');
            app.SegmentButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentButtonPushed, true);
            app.SegmentButton.Text = 'Segment';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = thresholding_techniques

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end